classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        TabGroup                matlab.ui.container.TabGroup       % Home, ...
        Tab                     matlab.ui.container.Tab            % Home
        Button                  matlab.ui.control.Button           % build ...
        Button2                 matlab.ui.control.Button           % Test
        LabelNumericEditField   matlab.ui.control.Label            % True
        True                    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % False
        False                   matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % True/I...
        ToverI                  matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % True/All
        ToverAll                matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Not found
        NotFound                matlab.ui.control.NumericEditField % [-Inf ...
        Tab2                    matlab.ui.container.Tab            % Settings
        ButtonGroup             matlab.ui.container.ButtonGroup   
        RadioButton             matlab.ui.control.RadioButton      % Defaul...
        RadioButton2            matlab.ui.control.RadioButton      % Custom
        LabelNumericEditField6  matlab.ui.control.Label            % Gauss_...
        GaussCoeff              matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField7  matlab.ui.control.Label            % Threshold
        Threshold               matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField8  matlab.ui.control.Label            % Filter...
        FilterBank              matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField9  matlab.ui.control.Label            % Number...
        CepstCoeff              matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField10 matlab.ui.control.Label            % Freque...
        MinFreq                 matlab.ui.control.NumericEditField % [-Inf ...
        MaxFreq                 matlab.ui.control.NumericEditField % [-Inf ...
        Label                   matlab.ui.control.Label            % Mel Ce...
        Label2                  matlab.ui.control.Label            % Gaussi...
        Label3                  matlab.ui.control.Label            % Decisi...
        CheckBox                matlab.ui.control.CheckBox         % Full C...
        SaveButton              matlab.ui.control.Button           % Save
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% Initiate variables for mfcc
%           Tw = 25;           % analysis frame duration (ms)
%           Ts = 10;           % analysis frame shift (ms)
%           alpha = 0.97;      % preemphasis coefficient
%           R = [ 300 3700 ];  % frequency range to consider
%           M = 20;            % number of filterbank channels 
%           C = 13;            % number of cepstral coefficients
%           L = 22;            % cepstral sine lifter parameter
%       
%           % hamming window (see Eq. (5.2) on p.73 of [1])
%           hamming = @(N)(0.54-0.46*cos(2*pi*[0:N-1].'/(N-1)));
%               [1] Young, S., Evermann, G., Gales, M., Hain, T., Kershaw, D., 
%               Liu, X., Moore, G., Odell, J., Ollason, D., Povey, D., 
%               Valtchev, V., Woodland, P., 2006. The HTK Book (for HTK 
%               Version 3.4.1). Engineering Department, Cambridge University.
%               (see also: http://htk.eng.cam.ac.uk)
global Tw;
global Ts;
global alpha;
global R;
global M;
global C;
global L;
global hamming;
global G_coeff;
global threshold;
global cov;
Tw = 25; 
Ts = 10;
alpha = 0.97;
R = [ 30 3700 ];
M = 20;
C = 13;
L = 22;
hamming = @(N)(0.54-0.46*cos(2*pi*[0:N-1].'/(N-1)));
G_coeff = 12;
threshold = 10;
cov = 0;
        end

        % Button button pushed function
        function Build(app)
% Global parameters for mfcc and GMM
global Tw;
global Ts;
global alpha;
global R;
global M;
global C;
global L;
global hamming;
global G_coeff;
global cov;

% Ask for directory

path=uigetdir();
directories = dir(path);
folders=[];
for i=3:numel(directories)
folders = [folders;cellstr(directories(i).name)];
end

 Nb = numel(folders);
 DatabaseName = char(inputdlg('Enter database name :' ,'Database name',[1 25]));
 if exist([DatabaseName '.mat'],'file') ~= 2
    % Initiate database with rows labels
    Database = {'label';'Samples'};
    save([DatabaseName '.mat'],'Database');
 end
 
 
% Setting up lading bar
loading = waitbar(0,'1','Name','Scanning...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
setappdata(loading,'canceling',0);
stop = 0;

% Scan every folder in the directory

for i = 1:Nb 
    load([DatabaseName '.mat']);
    PersonDb = cell2struct(Database,{'label','Samples'},1);
    
    
    folder = char(folders(i));
    new_path = [path '\' folder '\wav'];
   
    
    % take the folder name as the speaker name
    label = folder ;
    Person.label=label;
    % Scan each folder for audio files
    directories = dir(new_path);
    files=[];
    for j=3:numel(directories)
    files = [files;cellstr(directories(j).name)];
    end
    
    for j=1:numel(files)
        
    % Break on cancel    
    
    if getappdata(loading,'canceling')
        stop =1;
        break;
    end
        
    % Calculate coefficiens for every audio
    file_path = [new_path '\' char(files(j))];
    [voice,Fs] = audioread(file_path);
    [ MFCCs, ~, ~ ] = mfcc( voice, Fs, Tw, Ts, alpha, hamming, R, M, C, L );
     MFCCs = MFCCs(:,~isnan(MFCCs(1,:)));
    % cov = full covariance marices
     if (cov==0)
         [ m,v,w ]=gaussmix(MFCCs',[],[],G_coeff);
     else
         [ m,v,w ]=gaussmix(MFCCs',[],[],G_coeff,'v');
     end
    
    % Saves infos under the same name for all audios  
        Person.Samples(j).Cepst.m=m;
        Person.Samples(j).Cepst.v=v;
        Person.Samples(j).Cepst.w=w;
        Person.Samples(j).audio.audio=voice;
        Person.Samples(j).audio.Fs=Fs;
        Person.Samples(j).file=char(files(j));
        Person.Samples(j).Create_date=datestr(datetime('now')); 
        
     % Display loading update
        progress = (i-1) / Nb + j / numel(files) / Nb ;
        file = [folder '\' char(files(j))];
        waitbar(progress,loading,sprintf('%.2f %% \t %s',progress*100,file))
    end
    
    % Update and save Database
    
    PersonDb = [PersonDb;Person]; 
    Database = struct2cell(PersonDb);
    save([DatabaseName '.mat'],'Database','-append');
    clear Person;
    
    % Break on cancel 
    
    if (stop==1)
        break;
    end
end
delete(loading);
msgbox('Database updated!');


        end

        % Button2 button pushed function
        function Test(app)
            % Global parameters for mfcc and GMM
global Tw;
global Ts;
global alpha;
global R;
global M;
global C;
global L;
global hamming;
global G_coeff;
global threshold;
global cov;

% Ask for directory

path=uigetdir();
directories = dir(path);
folders=[];
for i=3:numel(directories)
folders = [folders;cellstr(directories(i).name)];
end

 Nb = numel(folders);
 
% Setting up lading bar
loading = waitbar(0,'1','Name','Scanning...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
setappdata(loading,'canceling',0);
stop = 0;

finds=[];
T=[];
F=[];
N=[];
n=1;

% Scan every folder in the directory
[Db_file,Db_path]=uigetfile('*.mat');
for i = 1:Nb 
    load([Db_path Db_file]);
    PersonDb = cell2struct(Database,{'label','Samples'},1);
    
    
    folder = char(folders(i));
    new_path = [path '\' folder '\wav'];
    
    % Scan each folder for audio files
    
    directories = dir(new_path);
    files=[];
    for j=3:numel(directories)
    files = [files;cellstr(directories(j).name)];
    end
    
    for j=1:numel(files)
        
    % Break on cancel    
    
    if getappdata(loading,'canceling')
        stop =1;
        break;
    end
        
    % Calculate coefficiens for every audio
    file_path = [new_path '\' char(files(j))];
    [voice,Fs] = audioread(file_path);
    [ MFCCs, ~, ~ ] = mfcc( voice, Fs, Tw, Ts, alpha, hamming, R, M, C, L );
     MFCCs = MFCCs(:,~isnan(MFCCs(1,:)));
    if (cov==0)
         [ m,v,w ]=gaussmix(MFCCs',[],[],G_coeff);
     else
         [ m,v,w ]=gaussmix(MFCCs',[],[],G_coeff,'v');
     end
    
    results = [];
   for k=2:numel(PersonDb)
       Person_result=[];
       for l=1:numel(PersonDb(k).Samples)
           [Person_result(l),~]=gaussmixk(m,v,w,PersonDb(k).Samples(l).Cepst.m,PersonDb(k).Samples(l).Cepst.v,PersonDb(k).Samples(l).Cepst.w); 
       end
       results(k-1) = mean(Person_result);
   end
   [min_dist,idx] = min(results);
   file = [folder '\' char(files(j))];
   
   if(min_dist<=threshold)
   finds(n)=strcmp(PersonDb(idx+1).label,folder);
   if finds(n)
       T = [T;cellstr(file)];
   else
       F = [F;cellstr(file) cellstr(PersonDb(idx+1).label)];
   end
   else
   finds(n) = -1;  
   N = [N;cellstr(file)];
   end
   
   n=n+1;
        
     % Display loading update
        progress = (i-1) / Nb + j / numel(files) / Nb ;
        waitbar(progress,loading,sprintf('%.2f %% \t %s',progress*100,file))
    end
          
    % Break on cancel 
    
    if (stop==1)
        break;
    end
end
t_val = numel(find(finds==1));
f_val = numel(find(finds==0));
n_val = numel(find(finds==-1));
app.True.Value = t_val;
app.False.Value = f_val;
app.NotFound.Value = n_val;
app.ToverI.Value =100* t_val/(t_val+f_val);
app.ToverAll.Value =100* t_val/(t_val+f_val+n_val);
file = fopen('True.txt', 'wt');
for i=1:numel(T)
fprintf(file,'%s\n',char(T(i)));
end
fclose(file);
file = fopen('False.txt', 'wt');
[row,~]=size(F);
for i=1:row
fprintf(file,'%s \t %s \n',char(F(i,1)),char(F(i,2)));
end
fclose(file);
file = fopen('Not identified.txt', 'wt');
for i=1:numel(N)
fprintf(file,'%s\n',char(N(i)));
end
fclose(file);
delete(loading);


        end

        % ButtonGroup selection change function
        function SettingChange(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            global R;
            global M;
            global C;
            global G_coeff;
            global threshold;
            global cov;
            if(strcmp(selectedButton.Text,'Custom'))
                app.LabelNumericEditField6.Enable = 'on';
                app.GaussCoeff.Enable = 'on';
                app.LabelNumericEditField7.Enable = 'on';
                app.Threshold.Enable = 'on';
                app.LabelNumericEditField8.Enable = 'on';
                app.FilterBank.Enable = 'on';
                app.LabelNumericEditField9.Enable = 'on';
                app.CepstCoeff.Enable = 'on';
                app.LabelNumericEditField10.Enable = 'on';
                app.MinFreq.Enable = 'on';
                app.MaxFreq.Enable = 'on';
                app.Label.Enable = 'on';
                app.Label2.Enable = 'on';
                app.Label3.Enable = 'on';
                app.CheckBox.Enable = 'on';
                app.SaveButton.Enable = 'on';
            else
                app.LabelNumericEditField6.Enable = 'off';
                app.GaussCoeff.Enable = 'off';
                app.LabelNumericEditField7.Enable = 'off';
                app.Threshold.Enable = 'off';
                app.LabelNumericEditField8.Enable = 'off';
                app.FilterBank.Enable = 'off';
                app.LabelNumericEditField9.Enable = 'off';
                app.CepstCoeff.Enable = 'off';
                app.LabelNumericEditField10.Enable = 'off';
                app.MinFreq.Enable = 'off';
                app.MaxFreq.Enable = 'off';
                app.Label.Enable = 'off';
                app.Label2.Enable = 'off';
                app.Label3.Enable = 'off';
                app.CheckBox.Enable = 'off';
                app.SaveButton.Enable = 'off';
                R = [ 30 3700 ];
                M = 20;
                C = 13;
                G_coeff = 16;
                threshold = 10;
                cov = 0;
                app.FilterBank.Value = 20;
                app.CepstCoeff.Value = 13;
                app.MinFreq.Value = 30;
                app.MaxFreq.Value = 3700;
                app.GaussCoeff.Value = 12;
                app.Threshold.Value = 10;
                app.CheckBox.Value = false;
            end
        end

        % SaveButton button pushed function
        function Save(app)
            global R;
            global M;
            global C;
            global G_coeff;
            global threshold;
            global cov;
            M = app.FilterBank.Value;
            C = app.CepstCoeff.Value;
            R = [app.MinFreq.Value app.MaxFreq.Value];
            G_coeff = app.GaussCoeff.Value;
            threshold = app.Threshold.Value;
            cov = app.CheckBox.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Home';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Build);
            app.Button.Text = 'build database';
            app.Button2 = uibutton(app.Tab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Test);
            app.Button2.Text = 'Test';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'True';
            app.True = uieditfield(app.Tab, 'numeric');
            app.True.Editable = 'off';
            app.LabelNumericEditField2 = uilabel(app.Tab);
            app.LabelNumericEditField2.Text = 'False';
            app.False = uieditfield(app.Tab, 'numeric');
            app.False.Editable = 'off';
            app.LabelNumericEditField4 = uilabel(app.Tab);
            app.LabelNumericEditField4.Text = 'True/Identified';
            app.ToverI = uieditfield(app.Tab, 'numeric');
            app.ToverI.Editable = 'off';
            app.LabelNumericEditField5 = uilabel(app.Tab);
            app.LabelNumericEditField5.Text = 'True/All';
            app.ToverAll = uieditfield(app.Tab, 'numeric');
            app.ToverAll.Editable = 'off';
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'Not found';
            app.NotFound = uieditfield(app.Tab, 'numeric');
            app.NotFound.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Settings';
            app.ButtonGroup = uibuttongroup(app.Tab2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SettingChange, true);
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Default Settings';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Custom';
            app.LabelNumericEditField6 = uilabel(app.ButtonGroup);
            app.LabelNumericEditField6.Enable = 'off';
            app.LabelNumericEditField6.Text = 'Gauss_Coeff Nb';
            app.GaussCoeff = uieditfield(app.ButtonGroup, 'numeric');
            app.GaussCoeff.Enable = 'off';
            app.GaussCoeff.Value = 12;
            app.LabelNumericEditField7 = uilabel(app.ButtonGroup);
            app.LabelNumericEditField7.Enable = 'off';
            app.LabelNumericEditField7.Text = 'Threshold';
            app.Threshold = uieditfield(app.ButtonGroup, 'numeric');
            app.Threshold.Enable = 'off';
            app.Threshold.Value = 10;
            app.LabelNumericEditField8 = uilabel(app.ButtonGroup);
            app.LabelNumericEditField8.Enable = 'off';
            app.LabelNumericEditField8.Text = 'FilterBank channels';
            app.FilterBank = uieditfield(app.ButtonGroup, 'numeric');
            app.FilterBank.Enable = 'off';
            app.FilterBank.Value = 20;
            app.LabelNumericEditField9 = uilabel(app.ButtonGroup);
            app.LabelNumericEditField9.Enable = 'off';
            app.LabelNumericEditField9.Text = 'Number of ceptral coefficiens';
            app.CepstCoeff = uieditfield(app.ButtonGroup, 'numeric');
            app.CepstCoeff.Enable = 'off';
            app.CepstCoeff.Value = 13;
            app.LabelNumericEditField10 = uilabel(app.ButtonGroup);
            app.LabelNumericEditField10.Enable = 'off';
            app.LabelNumericEditField10.Text = 'Frequency range';
            app.MinFreq = uieditfield(app.ButtonGroup, 'numeric');
            app.MinFreq.Enable = 'off';
            app.MinFreq.Value = 30;
            app.MaxFreq = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxFreq.Enable = 'off';
            app.MaxFreq.Value = 3700;
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Enable = 'off';
            app.Label.Text = 'Mel Cepstrum parameters.........................................................................................................................................................';
            app.Label2 = uilabel(app.ButtonGroup);
            app.Label2.Enable = 'off';
            app.Label2.Text = 'Gaussian Mixtures parameters ................................................................................................................................................';
            app.Label3 = uilabel(app.ButtonGroup);
            app.Label3.Enable = 'off';
            app.Label3.Text = 'Decision parameters ..................................................................................................................................................................';
            app.CheckBox = uicheckbox(app.ButtonGroup);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = 'Full Covariance Matrices';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Save);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end